classdef ABS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        EditField_5Label_7      matlab.ui.control.Label
        EditField_8             matlab.ui.control.NumericEditField
        AutoBrakingSystemLabel  matlab.ui.control.Label
        EditField_5Label_6      matlab.ui.control.Label
        EditField_5Label_5      matlab.ui.control.Label
        EditField_5             matlab.ui.control.NumericEditField
        Lamp                    matlab.ui.control.Lamp
        StopButton              matlab.ui.control.Button
        EditField_5Label_4      matlab.ui.control.Label
        EditField_5Label_3      matlab.ui.control.Label
        EditField_5Label        matlab.ui.control.Label
        VehicleSpeedGauge       matlab.ui.control.SemicircularGauge
        VehicleSpeedGaugeLabel  matlab.ui.control.Label
        RunButton               matlab.ui.control.Button
        EditField_4             matlab.ui.control.NumericEditField
        EditField_3             matlab.ui.control.NumericEditField
        EditField_2             matlab.ui.control.NumericEditField
        EditField               matlab.ui.control.NumericEditField
    end

    
    methods (Access = public)
       function updateGUI_Control(app, varargin)
              rto = get_param([bdroot,'/Audi_A8_Sedan_1/Display2'],'RuntimeObject');
              if(rto.InputPort(1).Data == 0)
              app.EditField.Value = 'Normal'; 
              app.EditField.FontColor = [0.00,1.00,0.00];
              elseif(rto.InputPort(1).Data == 1 || rto.InputPort(1).Data == 4)
              app.EditField.Value = 'Warning'; 
              app.EditField.FontColor = [0.878,0.906,0.133];
              elseif(rto.InputPort(1).Data == 2)
              app.EditField.Value = 'Partial Brake'; 
              app.EditField.FontColor = [0.93,0.69,0.13];
              elseif(rto.InputPort(1).Data == 3)
              app.EditField.Value = 'Full Brake'; 
              app.EditField.FontColor = [1.00,0.00,0.00];
              end
       end

       function updateGUI_Brake(app, varargin)
           rto = get_param([bdroot,'/Audi_A8_Sedan_1/Display3'],'RuntimeObject');
           app.EditField_4.Value = rto.InputPort(1).Data;
       end

       function updateGUI_Steering(app, varargin)
           rto = get_param([bdroot,'/Audi_A8_Sedan_1/Display4'],'RuntimeObject');
           app.EditField_5.Value = rto.InputPort(1).Data;
       end

       function updateGUI_Lane(app, varargin)
           rto = get_param([bdroot,'/Audi_A8_Sedan_1/Display'],'RuntimeObject');
           app.EditField_8.Value = rto.InputPort(1).Data;
       end

       function updateGUI_Driver_Status(app, varargin)
           rto = get_param([bdroot,'/Audi_A8_Sedan_1/Display11'],'RuntimeObject');
           app.EditField_2.Value = rto.InputPort(1).Data;
       end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            mdl = 'LMS_cs';
            open_system(mdl);
            out = sim(mdl,[0 60]);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;

        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            value = app.EditField_8.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Create a stop button
            %stopButton = uicontrol('Style', 'pushbutton', 'String', 'Stop', 'Position', [10 10 60 20]);

            % Set the callback function for the stop button
            %set(StopButton, 'Callback', @stopSimulation);

            % Callback function for the stop button
            %function stopSimulation(~, ~)
                % Stop the simulation
            set_param('LMS_cs', 'SimulationCommand','stop');
            %end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.VehicleSpeedGaugeLabel = uilabel(app.UIFigure);
            app.VehicleSpeedGaugeLabel.Text = 'Vehicle Speed';
            app.VehicleSpeedGauge = uigauge(app.UIFigure, 'semicircular');
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Text = 'Vehicle Status';
            app.EditField_5Label_3 = uilabel(app.UIFigure);
            app.EditField_5Label_3.Text = 'Auto/Manual';
            app.EditField_5Label_4 = uilabel(app.UIFigure);
            app.EditField_5Label_4.Text = 'Brake Pressure';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Lamp = uilamp(app.UIFigure);
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5Label_5 = uilabel(app.UIFigure);
            app.EditField_5Label_5.Text = 'Steering Angle';
            app.EditField_5Label_6 = uilabel(app.UIFigure);
            app.EditField_5Label_6.Text = 'Driver Status';
            app.AutoBrakingSystemLabel = uilabel(app.UIFigure);
            app.AutoBrakingSystemLabel.Text = 'Auto Braking System';
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_5Label_7 = uilabel(app.UIFigure);
            app.EditField_5Label_7.Text = 'Lane Number';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ABS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end