classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DriverDistractionLabel  matlab.ui.control.Label
        EditField_11            matlab.ui.control.EditField
        EditField_11Label       matlab.ui.control.Label
        DriverDrowsinessLabel   matlab.ui.control.Label
        EditField_10            matlab.ui.control.EditField
        EditField_10Label       matlab.ui.control.Label
        STOPButton              matlab.ui.control.Button
        Label_2                 matlab.ui.control.Label
        VehicleStateLabel       matlab.ui.control.Label
        EditField_8             matlab.ui.control.EditField
        EditField_8Label        matlab.ui.control.Label
        kmhLabel                matlab.ui.control.Label
        VehicleSpeedLabel       matlab.ui.control.Label
        EditField_7Label        matlab.ui.control.Label
        EditField_7             matlab.ui.control.NumericEditField
        degLabel                matlab.ui.control.Label
        SteeringAngleLabel      matlab.ui.control.Label
        EditField_6             matlab.ui.control.NumericEditField
        EditField_6Label        matlab.ui.control.Label
        barLabel                matlab.ui.control.Label
        BrakePressureLabel      matlab.ui.control.Label
        EditField_5             matlab.ui.control.NumericEditField
        EditField_5Label        matlab.ui.control.Label
        RunButton               matlab.ui.control.Button
        EditField_2             matlab.ui.control.EditField
        LaneNumberLabel         matlab.ui.control.Label
        EditField               matlab.ui.control.NumericEditField
        EditField_9             matlab.ui.control.EditField
        EditField_2Label        matlab.ui.control.Label
        EditFieldLabel          matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        function updateGUI_Control(app, varargin)
              rto = get_param([bdroot,'/Audi_A8_Sedan_1/Display2'],'RuntimeObject');
              if(rto.InputPort(1).Data == 0)
              app.EditField_8.Value = 'Normal'; 
              app.EditField_8.FontColor = [0.00,1.00,0.00];
              elseif(rto.InputPort(1).Data == 1 || rto.InputPort(1).Data == 4)
              app.EditField_8.Value = 'Warning'; 
              app.EditField_8.FontColor = [0.878,0.906,0.133];
              elseif(rto.InputPort(1).Data == 2)
              app.EditField_8.Value = 'Partial Brake'; 
              app.EditField_8.FontColor = [0.93,0.69,0.13];
              elseif(rto.InputPort(1).Data == 3)
              app.EditField_8.Value = 'Full Brake'; 
              app.EditField_8.FontColor = [1.00,0.00,0.00];
              end 
        end
        
        function updateGUI_Brake(app, varargin)
              rto = get_param([bdroot,'/Audi_A8_Sedan_1/Display3'],'RuntimeObject');
              app.EditField_5.Value = rto.InputPort(1).Data;
        end
        
        function updateGUI_Steering(app, varargin)
              rto = get_param([bdroot,'/Audi_A8_Sedan_1/Display13'],'RuntimeObject');
              app.EditField_6.Value = rto.InputPort(1).Data; 
        end
        
        function updateGUI_Lane(app, varargin)
              rto = get_param([bdroot,'/Audi_A8_Sedan_1/Display'],'RuntimeObject');
              app.EditField.Value = rto.InputPort(1).Data;
        end
        
        function updateGUI_Drowsiness(app, varargin)
              rto = get_param([bdroot,'/Audi_A8_Sedan_1/Display11'],'RuntimeObject');
              if(rto.InputPort(1).Data == 0)
                  app.EditField_10.Value = 'Normal'; 
                  app.EditField_10.FontColor = [0.00,1.00,0.00];
              elseif(rto.InputPort(1).Data == 1)
                  app.EditField_10.Value = 'Drowsy'; 
                  app.EditField_10.FontColor = [0.93,0.69,0.13];
              elseif(rto.InputPort(1).Data == 2)
                  app.EditField_10.Value = 'Extremly Drowsy'; 
                  app.EditField_10.FontColor = [1.00,0.00,0.00];
              end
        end

        function updateGUI_Distraction(app, varargin)
              rto = get_param([bdroot,'/Audi_A8_Sedan_1/Display14'],'RuntimeObject');
              if(rto.InputPort(1).Data == 0)
                  app.EditField_11.Value = 'Normal'; 
                  app.EditField_11.FontColor = [0.00,1.00,0.00];
              elseif(rto.InputPort(1).Data == 1)
                  app.EditField_11.Value = 'Distracted'; 
                  app.EditField_11.FontColor = [0.93,0.69,0.13];
              end
        end

        function updateGUI_Velocity(app, varargin)
              rto = get_param([bdroot,'/Audi_A8_Sedan_1/Display6'],'RuntimeObject');
              app.EditField_7.Value = 3.6 * rto.InputPort(1).Data; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            mdl = 'LMS_cs';
            open_system(mdl);
            out = sim(mdl,[0 60]);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            value = app.EditField_7.Value;
            
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            value = app.EditField_8.Value;
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            set_param('LMS_cs', 'SimulationCommand','stop');
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            value = app.EditField_10.Value;
            
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            value = app.EditField_11.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '';
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_9 = uieditfield(app.UIFigure, 'text');
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.LaneNumberLabel = uilabel(app.UIFigure);
            app.LaneNumberLabel.Text = 'Lane Number';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Text = '';
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.BrakePressureLabel = uilabel(app.UIFigure);
            app.BrakePressureLabel.Text = 'Brake Pressure';
            app.barLabel = uilabel(app.UIFigure);
            app.barLabel.Text = 'bar';
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Text = '';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.SteeringAngleLabel = uilabel(app.UIFigure);
            app.SteeringAngleLabel.Text = 'Steering Angle';
            app.degLabel = uilabel(app.UIFigure);
            app.degLabel.Text = 'deg';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_7Label = uilabel(app.UIFigure);
            app.EditField_7Label.Text = '';
            app.VehicleSpeedLabel = uilabel(app.UIFigure);
            app.VehicleSpeedLabel.Text = 'Vehicle Speed';
            app.kmhLabel = uilabel(app.UIFigure);
            app.kmhLabel.Text = 'km/h';
            app.EditField_8Label = uilabel(app.UIFigure);
            app.EditField_8Label.Text = '';
            app.EditField_8 = uieditfield(app.UIFigure, 'text');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.VehicleStateLabel = uilabel(app.UIFigure);
            app.VehicleStateLabel.Text = 'Vehicle State';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.EditField_10Label = uilabel(app.UIFigure);
            app.EditField_10Label.Text = '';
            app.EditField_10 = uieditfield(app.UIFigure, 'text');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.DriverDrowsinessLabel = uilabel(app.UIFigure);
            app.DriverDrowsinessLabel.Text = 'Driver Drowsiness';
            app.EditField_11Label = uilabel(app.UIFigure);
            app.EditField_11Label.Text = '';
            app.EditField_11 = uieditfield(app.UIFigure, 'text');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.DriverDistractionLabel = uilabel(app.UIFigure);
            app.DriverDistractionLabel.Text = 'Driver Distraction';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end